classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Resume            matlab.ui.control.Button
        EStop             matlab.ui.control.Button
        Z                 matlab.ui.control.NumericEditField
        ZEditFieldLabel   matlab.ui.control.Label
        Y                 matlab.ui.control.NumericEditField
        YEditFieldLabel   matlab.ui.control.Label
        X                 matlab.ui.control.NumericEditField
        XEditFieldLabel   matlab.ui.control.Label
        Link5             matlab.ui.control.Slider
        Link5SliderLabel  matlab.ui.control.Label
        Link4             matlab.ui.control.Slider
        Link4SliderLabel  matlab.ui.control.Label
        Link3             matlab.ui.control.Slider
        Link3SliderLabel  matlab.ui.control.Label
        Link2             matlab.ui.control.Slider
        Link2SliderLabel  matlab.ui.control.Label
        Link1             matlab.ui.control.Slider
        Link1SliderLabel  matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        robot 
        qRobot 
        arduino  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startUp(app)
            try
                % Create a serialport object and open the connection
                app.arduino = arduino('COM3', 'Uno'); % Ensure to use the correct port and board type
                disp("Arduino connected using MATLAB Support Package.");

                % app.robot = OmronTM5700();  % Instantiate the OmronTM5700 robot
                app.robot = welderRobot();

                % Ensure the qRobot matches the number of joints in the robot
                numJoints = app.robot.model.n;  % Get the number of joints (DOF) of the robot
                app.qRobot = zeros(1, numJoints);  % Initialize joint positions with zeros

                % Direct the plot to the UIAxes
                axes(app.UIAxes);  % Set the UIAxes as the active axes
                hold(app.UIAxes, 'on');  % Hold the axes for plotting

                % Set axes properties if needed
                app.UIAxes.XLim = [-1 1];
                app.UIAxes.YLim = [-1 1];
                app.UIAxes.ZLim = [-1 1];

                % Plot the robot in the UIAxes with the correct qRobot size
                app.robot.model.plot(app.qRobot, 'workspace', [-1 1 -1 1 -1 1]);  % Use workspace setting only

                hold(app.UIAxes, 'off');  % Release hold after plotting

            % catch ME
            %     disp("Failed to connect to Arduino. Please check the connection.");
            %     rethrow(ME);
            catch ME
                disp("Failed to initialise robot");
                disp(ME.message);
            end
        end

        % Button pushed function: Resume
        function resume(app, event)
            % Re-enable UI components except E-Stop 
            app.EStop.Enable = 'on'; 
            app.Link1.Enable = 'on';
            app.Link2.Enable = 'on';
            app.Link3.Enable = 'on';
            app.Link4.Enable = 'on';
            app.Link5.Enable = 'on'; 
            app.X.Enable = 'on';
            app.Y.Enable = 'on';
            app.Z.Enable = 'on';

            % Disable resume button again until another E-Stop is pressed
            app.Resume.Enable = 'off'; 

            % Control LEDs using the support package
            writeDigitalPin(app.arduino, 'D13', 0); % Turn off Green LED
            writeDigitalPin(app.arduino, 'D12', 1); % Turn on Red LED
    
            uialert(app.UIFigure, 'Robot Resumed', 'Resume'); 
        end

        % Button pushed function: EStop
        function eStop(app, event)
            % Disable UI components on emergency stop 
            app.EStop.Enable = 'off'; 
            app.Link1.Enable = 'off';
            app.Link2.Enable = 'off';
            app.Link3.Enable = 'off';
            app.Link4.Enable = 'off';
            app.Link5.Enable = 'off'; 
            app.X.Enable = 'off';
            app.Y.Enable = 'off';
            app.Z.Enable = 'off';

            app.Resume.Enable = 'on';
            app.EStop.Enable = 'off'; 

            % Control LEDs using the Support Package
            writeDigitalPin(app.arduino, 'D13', 1); % Turn on Green LED
            writeDigitalPin(app.arduino, 'D12', 0); % Turn off Red LED

            uialert(app.UIFigure, 'Robot Stopped', 'Emergency Stop');

        end

        % Value changed function: Link1
        function Link1Changed(app, event)

        end

        % Value changed function: Link2
        function Link2Changed(app, event)
            % Get the new joint angle from the slider
            jointValue = app.Link2.Value;
            % Update the robot's joint configuration
            app.qRobot(2) = jointValue;
            % Re-animate the robot with updated joint angles
            app.robot.model.animate(app.qRobot);
        end

        % Value changed function: Link3
        function Link3Changed(app, event)
            % Get the new joint angle from the slider
            jointValue = app.Link3.Value;
            % Update the robot's joint configuration
            app.qRobot(3) = jointValue;
            % Re-animate the robot with updated joint angles
            app.robot.model.animate(app.qRobot);
        end

        % Value changed function: Link4
        function Link4Changed(app, event)
            % Get the new joint angle from the slider
            jointValue = app.Link4.Value;
            % Update the robot's joint configuration
            app.qRobot(4) = jointValue;
            % Re-animate the robot with updated joint angles
            app.robot.model.animate(app.qRobot);
        end

        % Value changed function: Link5
        function Link5Changed(app, event)
            % Get the new joint angle from the slider
            jointValue = app.Link5.Value;
            % Update the robot's joint configuration
            app.qRobot(5) = jointValue;
            % Re-animate the robot with updated joint angles
            app.robot.model.animate(app.qRobot);
        end

        % Value changed function: X
        function MoveButtonPushed(app, event)
            % Get the X, Y, Z values from the Numeric Edit Fields
            x = app.XEditField.Value;
            y = app.YEditField.Value;
            z = app.ZEditField.Value;
        
            % Compute the inverse kinematics to get joint angles for the desired position
            T = transl([x, y, z]);  % Target Cartesian position
            qSol = app.robot.model.ikcon(T, app.qRobot);  % Solve IK
        
            % If a solution exists, move the robot
            if ~isempty(qSol)
                app.qRobot = qSol;  % Update the robot's joint configuration
                app.robot.model.animate(app.qRobot);  % Animate the robot
            else
                % Display an alert if no solution is found
                uialert(app.UIFigure, 'No valid IK solution found', 'Error');
            end
        end

        % Callback function: not associated with a component
        function Link1Changing(app, event)

        end

        % Callback function: not associated with a component
        function Link2Changing(app, event)

        end

        % Callback function: not associated with a component
        function Link3Changing(app, event)

        end

        % Callback function: not associated with a component
        function Link4Changing(app, event)

        end

        % Callback function: not associated with a component
        function Link5Changing(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Omron TM5700')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Link1SliderLabel = uilabel(app.UIFigure);
            app.Link1SliderLabel.Text = 'Link 1';
            app.Link1 = uislider(app.UIFigure);
            app.Link1.Limits = [-3.14159265358979 3.14159265358979];
            app.Link1.ValueChangedFcn = createCallbackFcn(app, @Link1Changed, true);
            app.Link2SliderLabel = uilabel(app.UIFigure);
            app.Link2SliderLabel.Text = 'Link 2';
            app.Link2 = uislider(app.UIFigure);
            app.Link2.Limits = [-3.14159265358979 3.14159265358979];
            app.Link2.ValueChangedFcn = createCallbackFcn(app, @Link2Changed, true);
            app.Link3SliderLabel = uilabel(app.UIFigure);
            app.Link3SliderLabel.Text = 'Link 3';
            app.Link3 = uislider(app.UIFigure);
            app.Link3.Limits = [-3.14159265358979 3.14159265358979];
            app.Link3.ValueChangedFcn = createCallbackFcn(app, @Link3Changed, true);
            app.Link4SliderLabel = uilabel(app.UIFigure);
            app.Link4SliderLabel.Text = 'Link 4';
            app.Link4 = uislider(app.UIFigure);
            app.Link4.Limits = [-3.14159265358979 3.14159265358979];
            app.Link4.ValueChangedFcn = createCallbackFcn(app, @Link4Changed, true);
            app.Link5SliderLabel = uilabel(app.UIFigure);
            app.Link5SliderLabel.Text = 'Link 5';
            app.Link5 = uislider(app.UIFigure);
            app.Link5.Limits = [-3.14159265358979 3.14159265358979];
            app.Link5.ValueChangedFcn = createCallbackFcn(app, @Link5Changed, true);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.X = uieditfield(app.UIFigure, 'numeric');
            app.X.ValueChangedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.Y = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.Z = uieditfield(app.UIFigure, 'numeric');
            app.EStop = uibutton(app.UIFigure, 'push');
            app.EStop.ButtonPushedFcn = createCallbackFcn(app, @eStop, true);
            app.EStop.Icon = fullfile(pathToMLAPP, 'EStop.png');
            app.EStop.Text = '';
            app.Resume = uibutton(app.UIFigure, 'push');
            app.Resume.ButtonPushedFcn = createCallbackFcn(app, @resume, true);
            app.Resume.Icon = fullfile(pathToMLAPP, 'Resume.png');
            app.Resume.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end