classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Z_2                 matlab.ui.control.NumericEditField
        ZEditFieldLabel_2   matlab.ui.control.Label
        Y_2                 matlab.ui.control.NumericEditField
        YEditFieldLabel_2   matlab.ui.control.Label
        X_2                 matlab.ui.control.NumericEditField
        XEditFieldLabel_2   matlab.ui.control.Label
        Link5_2             matlab.ui.control.Slider
        Link4_2             matlab.ui.control.Slider
        Link3_2             matlab.ui.control.Slider
        Link2_2             matlab.ui.control.Slider
        Link1_2             matlab.ui.control.Slider
        WelderRSLabel       matlab.ui.control.Label
        Link5SliderLabel_2  matlab.ui.control.Label
        Link4SliderLabel_2  matlab.ui.control.Label
        Link3SliderLabel_2  matlab.ui.control.Label
        Link2SliderLabel_2  matlab.ui.control.Label
        Link1SliderLabel_3  matlab.ui.control.Label
        OMRON5700Label      matlab.ui.control.Label
        Resume              matlab.ui.control.Button
        EStop               matlab.ui.control.Button
        Z                   matlab.ui.control.NumericEditField
        ZEditFieldLabel     matlab.ui.control.Label
        Y                   matlab.ui.control.NumericEditField
        YEditFieldLabel     matlab.ui.control.Label
        X                   matlab.ui.control.NumericEditField
        XEditFieldLabel     matlab.ui.control.Label
        Link5               matlab.ui.control.Slider
        Link5SliderLabel    matlab.ui.control.Label
        Link4               matlab.ui.control.Slider
        Link4SliderLabel    matlab.ui.control.Label
        Link3               matlab.ui.control.Slider
        Link3SliderLabel    matlab.ui.control.Label
        Link2               matlab.ui.control.Slider
        Link2SliderLabel    matlab.ui.control.Label
        Link1               matlab.ui.control.Slider
        Link1SliderLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        qRobot 
        arduino  
        robot
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startUp(app)
            try
                % Create a serialport object and open the connection
                app.arduino = serialport('COM3', 9600); % Serial communication to Arduino
                disp("Arduino connected using MATLAB Support Package.");

                % Ensure the qRobot matches the number of joints in the robot
                app.robot = OmronTM5700(); 
                numJoints = app.robot.model.n;
                app.qRobot = zeros(1, numJoints); 
                % app.qRobot1 = zeros(1, app.robot1.model.n);
                % app.qRobot2 = zeros(1, app.robot2.model.n);
                
                % Ensure app.robot is an OmronTM5700 object with a SerialLink model
                if isa(app.robot, 'OmronTM5700') && isprop(app.robot, 'model')
                    app.robot.model.plot(app.qRobot, 'workspace', [-1 1 -1 1 -1 1]);
                else
                    disp('app.robot not initialized correctly');
                end


                % app.robot = OmronTM5700(); 
                % app.robot2 = welderRobot();
                
                % app.qRobot = zeros(1,5);
                % app.qRobot2 = zeros(1,5);
                
                % Direct the plot to the UIAxes
                axes(app.UIAxes);
                hold(app.UIAxes, 'on');
                app.UIAxes.XLim = [-1 1];
                app.UIAxes.YLim = [-1 1];
                app.UIAxes.ZLim = [-1 1];
                app.qRobot.model.plot(app.qRobot, 'workspace', [-1 1 -1 1 -1 1]);
                % app.qRobot2.model.plot(app.qrobot2, 'workspace', [-1 1 -1 1 -1 1]);
                hold(app.UIAxes, 'off');

                % Set up a timer to periodically check for serial data from Arduino
                app.serialListener = timer('ExecutionMode', 'fixedRate', ...
                                           'Period', 0.1, ...
                                           'TimerFcn', @(~, ~) app.checkSerialInput());
                start(app.serialListener);

            catch ME
                disp("Failed to initialise robot");
                disp(ME.message);
            end
        end

        % Function to check serial input from Arduino
        function checkSerialInput(app)
            try
                if app.arduino.NumBytesAvailable > 0
                    data = readline(app.arduino);  % Read data from Arduino
                    data = strtrim(data);  % Remove trailing newline character
                    
                    if strcmp(data, 'eStop')  % If Arduino sends "eStop"
                        app.eStop();  % Trigger the E-stop function in MATLAB
                    end
                end
            catch ME
                disp(['Serial read error: ' ME.message]);
            end
        
        
        % % Callback for joint slider control
        % function controlJoint(app, robotID, jointIndex, value)
        %     if robotID == 1
        %         app.qRobot1(jointIndex) = value;
        %         app.robot1.model.animate(app.qRobot1); % Update animation
        %     else
        %         app.qRobot2(jointIndex) = value;
        %         app.robot2.model.animate(app.qRobot2); % Update animation
        %     end
        end

        % Button pushed function: Resume
        function resume(app, event)
            % Re-enable UI components except E-Stop 
            app.EStop.Enable = 'on'; 
            app.Link1.Enable = 'on';
            app.Link2.Enable = 'on';
            app.Link3.Enable = 'on';
            app.Link4.Enable = 'on';
            app.Link5.Enable = 'on'; 
            app.WelderLink1.Enable = 'on';

            app.X.Enable = 'on';
            app.Y.Enable = 'on';
            app.Z.Enable = 'on';

            % Disable resume button again until another E-Stop is pressed
            app.Resume.Enable = 'off'; 

            % Control LEDs using the support package
            writeDigitalPin(app.arduino, 'D13', 0); % Turn off Green LED
            writeDigitalPin(app.arduino, 'D12', 1); % Turn on Red LED
    
            uialert(app.UIFigure, 'Robot Resumed', 'Resume'); 
        end

        % Button pushed function: EStop
        function eStop(app, event)
            % Disable UI components on emergency stop 
            app.EStop.Enable = 'off'; 
            app.Link1.Enable = 'off';
            app.Link2.Enable = 'off';
            app.Link3.Enable = 'off';
            app.Link4.Enable = 'off';
            app.Link5.Enable = 'off'; 
            app.WelderLink1.Enable = 'off';
            app.X.Enable = 'off';
            app.Y.Enable = 'off';
            app.Z.Enable = 'off';

            app.Resume.Enable = 'on';
            app.EStop.Enable = 'off'; 

            % Control LEDs using the Support Package
            writeline(app.arduino, 'clearled');  % Send "clearled" command to Arduino

            uialert(app.UIFigure, 'Robot Stopped', 'Emergency Stop');

        end

        % Value changed function: Link1
        function Link1Changed(app, event)
            % Get the new joint angle from the slider
            jointValue = app.Link1.Value;
            % Update the robot's joint configuration
            app.qRobot(1) = jointValue;
            % Re-animate the robot with updated joint angles
            app.robot.model.animate(app.qRobot);
        end

        % Value changed function: Link2
        function Link2Changed(app, event)
            % Get the new joint angle from the slider
            jointValue = app.Link2.Value;
            % Update the robot's joint configuration
            app.qRobot(2) = jointValue;
            % Re-animate the robot with updated joint angles
            app.robot.model.animate(app.qRobot);
        end

        % Value changed function: Link3
        function Link3Changed(app, event)
            % Get the new joint angle from the slider
            jointValue = app.Link3.Value;
            % Update the robot's joint configuration
            app.qRobot(3) = jointValue;
            % Re-animate the robot with updated joint angles
            app.robot.model.animate(app.qRobot);
        end

        % Value changed function: Link4
        function Link4Changed(app, event)
            % Get the new joint angle from the slider
            jointValue = app.Link4.Value;
            % Update the robot's joint configuration
            app.qRobot(4) = jointValue;
            % Re-animate the robot with updated joint angles
            app.robot.model.animate(app.qRobot);
        end

        % Value changed function: Link5
        function Link5Changed(app, event)
            % Get the new joint angle from the slider
            jointValue = app.Link5.Value;
            % Update the robot's joint configuration
            app.qRobot(5) = jointValue;
            % Re-animate the robot with updated joint angles
            app.robot.model.animate(app.qRobot);
        end

        % Value changed function: X
        function MoveButtonPushed(app, event)
            % Get the X, Y, Z values from the Numeric Edit Fields
            x = app.X.Value;
            y = app.Y.Value;
            z = app.Z.Value;

            % Compute the inverse kinematics to get joint angles for the desired position
            T = transl([x, y, z]);  % Target Cartesian position
            qSol = app.robot.model.ikcon(T, app.qRobot);  % Solve IK

            % If a solution exists, move the robot
            if ~isempty(qSol)
                app.qRobot = qSol;  % Update the robot's joint configuration
                app.robot.model.animate(app.qRobot);  % Animate the robot
            else
                % Display an alert if no solution is found
                uialert(app.UIFigure, 'No valid IK solution found', 'Error');
            end

            % x = xField.Value;
            % y = yField.Value;
            % z = zField.Value;
            % targetTransform = transl(x, y, z);
            % 
            % if robotID == 1
            %     qSol = app.robot1.model.ikcon(targetTransform, app.qRobot1);
            %     if ~isempty(qSol)
            %         app.qRobot1 = qSol;
            %         app.robot1.model.animate(app.qRobot1);
            %     end
            % else
            %     qSol = app.robot2.model.ikcon(targetTransform, app.qRobot2);
            %     if ~isempty(qSol)
            %         app.qRobot2 = qSol;
            %         app.robot2.model.animate(app.qRobot2);
            %     end
            % end
        end

        % Callback function
        function Link1Changing(app, event)

        end

        % Callback function
        function Link2Changing(app, event)

        end

        % Callback function
        function Link3Changing(app, event)

        end

        % Callback function
        function Link4Changing(app, event)

        end

        % Callback function
        function Link5Changing(app, event)

        end

        % Value changed function: Link1_2
        function WelderLink1(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Link1SliderLabel = uilabel(app.UIFigure);
            app.Link1SliderLabel.Text = 'Link 1';
            app.Link1 = uislider(app.UIFigure);
            app.Link1.Limits = [-3.14159265358979 3.14159265358979];
            app.Link1.ValueChangedFcn = createCallbackFcn(app, @Link1Changed, true);
            app.Link2SliderLabel = uilabel(app.UIFigure);
            app.Link2SliderLabel.Text = 'Link 2';
            app.Link2 = uislider(app.UIFigure);
            app.Link2.Limits = [-3.14159265358979 3.14159265358979];
            app.Link2.ValueChangedFcn = createCallbackFcn(app, @Link2Changed, true);
            app.Link3SliderLabel = uilabel(app.UIFigure);
            app.Link3SliderLabel.Text = 'Link 3';
            app.Link3 = uislider(app.UIFigure);
            app.Link3.Limits = [-3.14159265358979 3.14159265358979];
            app.Link3.ValueChangedFcn = createCallbackFcn(app, @Link3Changed, true);
            app.Link4SliderLabel = uilabel(app.UIFigure);
            app.Link4SliderLabel.Text = 'Link 4';
            app.Link4 = uislider(app.UIFigure);
            app.Link4.Limits = [-3.14159265358979 3.14159265358979];
            app.Link4.ValueChangedFcn = createCallbackFcn(app, @Link4Changed, true);
            app.Link5SliderLabel = uilabel(app.UIFigure);
            app.Link5SliderLabel.Text = 'Link 5';
            app.Link5 = uislider(app.UIFigure);
            app.Link5.Limits = [-3.14159265358979 3.14159265358979];
            app.Link5.ValueChangedFcn = createCallbackFcn(app, @Link5Changed, true);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.X = uieditfield(app.UIFigure, 'numeric');
            app.X.ValueChangedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.Y = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.Z = uieditfield(app.UIFigure, 'numeric');
            app.EStop = uibutton(app.UIFigure, 'push');
            app.EStop.ButtonPushedFcn = createCallbackFcn(app, @eStop, true);
            app.EStop.Icon = fullfile(pathToMLAPP, 'EStop.png');
            app.EStop.Text = '';
            app.Resume = uibutton(app.UIFigure, 'push');
            app.Resume.ButtonPushedFcn = createCallbackFcn(app, @resume, true);
            app.Resume.Icon = fullfile(pathToMLAPP, 'Resume.png');
            app.Resume.Text = '';
            app.OMRON5700Label = uilabel(app.UIFigure);
            app.OMRON5700Label.Text = 'OMRON5700';
            app.Link1SliderLabel_3 = uilabel(app.UIFigure);
            app.Link1SliderLabel_3.Text = 'Link 1';
            app.Link2SliderLabel_2 = uilabel(app.UIFigure);
            app.Link2SliderLabel_2.Text = 'Link 2';
            app.Link3SliderLabel_2 = uilabel(app.UIFigure);
            app.Link3SliderLabel_2.Text = 'Link 3';
            app.Link4SliderLabel_2 = uilabel(app.UIFigure);
            app.Link4SliderLabel_2.Text = 'Link 4';
            app.Link5SliderLabel_2 = uilabel(app.UIFigure);
            app.Link5SliderLabel_2.Text = 'Link 5';
            app.WelderRSLabel = uilabel(app.UIFigure);
            app.WelderRSLabel.Text = 'WelderRS';
            app.Link1_2 = uislider(app.UIFigure);
            app.Link1_2.Limits = [-3.14159265358979 3.14159265358979];
            app.Link1_2.ValueChangedFcn = createCallbackFcn(app, @WelderLink1, true);
            app.Link2_2 = uislider(app.UIFigure);
            app.Link2_2.Limits = [-3.14159265358979 3.14159265358979];
            app.Link3_2 = uislider(app.UIFigure);
            app.Link3_2.Limits = [-3.14159265358979 3.14159265358979];
            app.Link4_2 = uislider(app.UIFigure);
            app.Link4_2.Limits = [-3.14159265358979 3.14159265358979];
            app.Link5_2 = uislider(app.UIFigure);
            app.Link5_2.Limits = [-3.14159265358979 3.14159265358979];
            app.XEditFieldLabel_2 = uilabel(app.UIFigure);
            app.XEditFieldLabel_2.Text = 'X';
            app.X_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel_2 = uilabel(app.UIFigure);
            app.YEditFieldLabel_2.Text = 'Y';
            app.Y_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel_2 = uilabel(app.UIFigure);
            app.ZEditFieldLabel_2.Text = 'Z';
            app.Z_2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end